classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RandomVariableTab               matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AserOsamaLabel                  matlab.ui.control.Label
        SaifeldenMohamedLabel           matlab.ui.control.Label
        ExperimenttypeDropDown          matlab.ui.control.DropDown
        ExperimenttypeDropDownLabel     matlab.ui.control.Label
        SampleFile                      matlab.ui.control.EditField
        RunRandomVariableButton         matlab.ui.control.Button
        ImportRandomVariableButton      matlab.ui.control.Button
        MeanValueLabel                  matlab.ui.control.Label
        RandomVariableMeanLabel         matlab.ui.control.Label
        ThirdMomentValueLabel           matlab.ui.control.Label
        RandomVariableThirdMomentLabel  matlab.ui.control.Label
        VarianceValueLabel              matlab.ui.control.Label
        RandomVariableVarianceLabel     matlab.ui.control.Label
        RandomVariableLabel             matlab.ui.control.Label
        CIE327ProjectLabel              matlab.ui.control.Label
        InvalidSampleFileWarning        matlab.ui.control.Label
        GarbageLabel                    matlab.ui.control.Label
        ExpParam2Label                  matlab.ui.control.Label
        ExpParam1Label                  matlab.ui.control.Label
        ExpParam2                       matlab.ui.control.NumericEditField
        ExpParam1                       matlab.ui.control.NumericEditField
        SecMoment                       matlab.ui.control.UIAxes
        FirMoment                       matlab.ui.control.UIAxes
        mgf                             matlab.ui.control.UIAxes
        RandomProcessTab                matlab.ui.container.Tab
    end


    properties (Access = private)
        MyFilePath % Description
        SampleData % Description
        CanRun = 0
        ExpType = "uniform" % Description
    end

    methods (Access = private)


        function [] = RunUniform(app)
            app.GarbageLabel.Text = "UNIFORM";
            a_in = app.ExpParam1.Value;
            b_in = app.ExpParam2.Value;
            x = sort(app.SampleData);
            if ((a_in < x(end) && b_in > x(1)))
                if (x(1) > a_in)
                    a = x(1);
                else
                    %                 closest = find( min( abs(x-a_in) )== abs(x-a_in) );
                    %                 a = x(closest);
                    a = a_in;
                end
                if (x(end) < b_in)
                    b = x(end);
                else
                    %                 closest = find( min( abs(x-b_in) )== abs(x-b_in) );
                    %                 b = x(closest);
                    b = b_in;

                end
                app.InvalidSampleFileWarning.Text = "";
            else
                a = 0;
                b = 0;
                app.InvalidSampleFileWarning.FontColor = "red";
                app.InvalidSampleFileWarning.Text = "A & B out of range of sample file.";
            end
            mean = (a + b) / 2;
            variance = ((b-a) ^ 2)/12;
            x3_fx = @(x) (1/(b-a)) .* x.^3;
            thirdMoment = integral(x3_fx, a, b);
            app.MeanValueLabel.Text = string(mean);
            app.VarianceValueLabel.Text = string(variance);
            app.ThirdMomentValueLabel.Text = string(thirdMoment);

        end

        function [] = RunNormal(app)
            app.GarbageLabel.Text = "NORMAL";
            mean = app.ExpParam1.Value;
            stdev = app.ExpParam2.Value;
            variance = stdev^2;

            x = sort(app.SampleData);

            k = (1/sqrt(2*pi*variance));
            fun = @(x) (x.^3).*(k .* exp( ...
                (  (- ( (x - mean) .^2) ) ...
                ./ (2 .* variance))));

            format long;
            thirdMoment = integral(fun,x(1),x(end));

            app.MeanValueLabel.Text = string(mean);
            app.VarianceValueLabel.Text = string(variance);
            app.ThirdMomentValueLabel.Text = string(thirdMoment);
            app.InvalidSampleFileWarning.Text = "";

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportRandomVariableButton
        function ImportRandomVariableButtonPushed(app, event)
            app.MyFilePath = app.SampleFile.Value;
            try
                app.SampleData = load(app.MyFilePath).s;
                app.InvalidSampleFileWarning.FontColor = "green";
                app.InvalidSampleFileWarning.Text ="File loaded sucessfully";
                app.CanRun = 1;
            catch
                app.InvalidSampleFileWarning.FontColor = "red";
                app.InvalidSampleFileWarning.Text = "You entered an invalid sample file. File has to be a .mat matlab file with your sample stored in a variable named `s`";
                app.CanRun = 0;
            end
        end

        % Value changed function: ExperimenttypeDropDown
        function ExperimenttypeDropDownValueChanged(app, event)
            value = app.ExperimenttypeDropDown.Value;
            if (value == "Uniform Distribution")
                app.ExpType = "uniform";
                app.ExpParam1Label.Text = "A (Start)";
                app.ExpParam2Label.Text = "B (End)";
            else
                if (value == "Normal Distribution")
                    app.ExpType= "normal";
                    app.ExpParam1Label.Text = "Mu (Mean)";
                    app.ExpParam2Label.Text = "Sigma (Std. Dist.)";
                end
            end
        end

        % Button pushed function: RunRandomVariableButton
        function RunRandomVariableButtonPushed(app, event)
            if (app.CanRun)
                app.SampleData = sort(app.SampleData);

                if (app.ExpType == "uniform")
                    app.RunUniform();
                elseif (app.ExpType == "normal")
                    app.RunNormal();
                end
            else
                app.InvalidSampleFileWarning.FontColor = "red";
                app.InvalidSampleFileWarning.Text = "Please enter a (working) sample file";

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RandomVariableTab = uitab(app.TabGroup);
            app.RandomVariableTab.Title = 'Random Variable';
            app.mgf = uiaxes(app.RandomVariableTab);
            title(app.mgf, 'MGF')
            xlabel(app.mgf, 'X')
            ylabel(app.mgf, 'Y')
            zlabel(app.mgf, 'Z')
            app.FirMoment = uiaxes(app.RandomVariableTab);
            title(app.FirMoment, 'First Moment')
            xlabel(app.FirMoment, 'X')
            ylabel(app.FirMoment, 'Y')
            zlabel(app.FirMoment, 'Z')
            app.SecMoment = uiaxes(app.RandomVariableTab);
            title(app.SecMoment, 'Secont Moment')
            xlabel(app.SecMoment, 'X')
            ylabel(app.SecMoment, 'Y')
            zlabel(app.SecMoment, 'Z')
            app.ExpParam1 = uieditfield(app.RandomVariableTab, 'numeric');
            app.ExpParam2 = uieditfield(app.RandomVariableTab, 'numeric');
            app.ExpParam1Label = uilabel(app.RandomVariableTab);
            app.ExpParam1Label.Text = 'A (Start)';
            app.ExpParam2Label = uilabel(app.RandomVariableTab);
            app.ExpParam2Label.Text = 'B (End)';
            app.GarbageLabel = uilabel(app.RandomVariableTab);
            app.GarbageLabel.Text = 'Garbage';
            app.InvalidSampleFileWarning = uilabel(app.RandomVariableTab);
            app.InvalidSampleFileWarning.Text = '';
            app.CIE327ProjectLabel = uilabel(app.RandomVariableTab);
            app.CIE327ProjectLabel.Text = 'CIE 327 Project';
            app.RandomVariableLabel = uilabel(app.RandomVariableTab);
            app.RandomVariableLabel.Text = 'Random Variable';
            app.RandomVariableVarianceLabel = uilabel(app.RandomVariableTab);
            app.RandomVariableVarianceLabel.Text = 'Random Variable Variance:';
            app.VarianceValueLabel = uilabel(app.RandomVariableTab);
            app.VarianceValueLabel.Text = 'VarianceValue';
            app.RandomVariableThirdMomentLabel = uilabel(app.RandomVariableTab);
            app.RandomVariableThirdMomentLabel.Text = 'Random Variable Third Moment:';
            app.ThirdMomentValueLabel = uilabel(app.RandomVariableTab);
            app.ThirdMomentValueLabel.Text = 'ThirdMomentValue';
            app.RandomVariableMeanLabel = uilabel(app.RandomVariableTab);
            app.RandomVariableMeanLabel.Text = 'Random Variable Mean:';
            app.MeanValueLabel = uilabel(app.RandomVariableTab);
            app.MeanValueLabel.Text = 'MeanValue';
            app.ImportRandomVariableButton = uibutton(app.RandomVariableTab, 'push');
            app.ImportRandomVariableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRandomVariableButtonPushed, true);
            app.ImportRandomVariableButton.Text = {'Import '; 'Random'; 'Variable'};
            app.RunRandomVariableButton = uibutton(app.RandomVariableTab, 'push');
            app.RunRandomVariableButton.ButtonPushedFcn = createCallbackFcn(app, @RunRandomVariableButtonPushed, true);
            app.RunRandomVariableButton.Text = 'Run Random Variable';
            app.SampleFile = uieditfield(app.RandomVariableTab, 'text');
            app.SampleFile.Placeholder = 'EnterSampleFile.m';
            app.ExperimenttypeDropDownLabel = uilabel(app.RandomVariableTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.RandomVariableTab);
            app.ExperimenttypeDropDown.Items = {'Uniform Distribution', 'Normal Distribution'};
            app.ExperimenttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimenttypeDropDownValueChanged, true);
            app.ExperimenttypeDropDown.Value = 'Uniform Distribution';
            app.SaifeldenMohamedLabel = uilabel(app.RandomVariableTab);
            app.SaifeldenMohamedLabel.Text = 'Saifelden Mohamed ';
            app.AserOsamaLabel = uilabel(app.RandomVariableTab);
            app.AserOsamaLabel.Text = 'Aser Osama';
            app.Label = uilabel(app.RandomVariableTab);
            app.Label.Text = '202100432';
            app.Label_2 = uilabel(app.RandomVariableTab);
            app.Label_2.Text = '202101266';
            app.RandomProcessTab = uitab(app.TabGroup);
            app.RandomProcessTab.Title = 'Random Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end